classdef proj_control_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Projection_prep_tab            matlab.ui.container.Tab
        Parameters                     matlab.ui.container.Panel
        FlipverticalCheckBox           matlab.ui.control.CheckBox
        FliphorizontalCheckBox         matlab.ui.control.CheckBox
        ArrayverticalcopiesSpinnerLabel  matlab.ui.control.Label
        ArrayverticalcopiesSpinner     matlab.ui.control.Spinner
        RotateLabel                    matlab.ui.control.Label
        Rotateproj                     matlab.ui.control.NumericEditField
        ScreenwidthpixelsLabel         matlab.ui.control.Label
        Screenwidthpixels              matlab.ui.control.NumericEditField
        ScreenheightpixelsLabel        matlab.ui.control.Label
        Screenheightpixels             matlab.ui.control.NumericEditField
        SizescalefactorLabel           matlab.ui.control.Label
        Sizescalefactor                matlab.ui.control.NumericEditField
        IntensityscalefactorLabel      matlab.ui.control.Label
        Intensityscalefactor           matlab.ui.control.NumericEditField
        Resetparameters                matlab.ui.control.Button
        prep_proj_button               matlab.ui.control.Button
        ArrayverticalshiftpixelsLabel  matlab.ui.control.Label
        Array_shift                    matlab.ui.control.NumericEditField
        VerticalshiftpixelsLabel       matlab.ui.control.Label
        Vertical_shift                 matlab.ui.control.NumericEditField
        HorizontalshiftpixelsLabel     matlab.ui.control.Label
        Horizontal_shift               matlab.ui.control.NumericEditField
        FileselectionPanel             matlab.ui.container.Panel
        Open_proj_file_button          matlab.ui.control.Button
        curr_proj_file_label           matlab.ui.control.Label
        PreviewprojectionsPanel        matlab.ui.container.Panel
        prep_proj_axes                 matlab.ui.control.UIAxes
        prep_proj_slider               matlab.ui.control.Slider
        prep_proj_slice_label          matlab.ui.control.Label
        Createprojectionset            matlab.ui.control.Button
        Saveprojectionset              matlab.ui.control.Button
        Projection_tab                 matlab.ui.container.Tab
        run_proj_axes                  matlab.ui.control.UIAxes
        Start_proj_button              matlab.ui.control.Button
        Stop_proj_button               matlab.ui.control.Button
        run_proj_slider                matlab.ui.control.Slider
        run_proj_slice_label           matlab.ui.control.Label
        Motorcontrolpanel              matlab.ui.container.Panel
        rot_vel_label                  matlab.ui.control.Label
        curr_rot_vel                   matlab.ui.control.EditField
        rot_acc_label                  matlab.ui.control.Label
        curr_rot_acc                   matlab.ui.control.EditField
    end

    
    properties (Access = public)
        projections_mat % Description
        projections_opt_mat_original
        projections_cell_array
        projections_cell_array_original
        
        
    end

    
    methods (Access = private)
        
        function disp_prep_projection(app,S)
            
            % Display image and stretch to fill axes
            I = imagesc(app.projections_cell_array{S}, 'Parent', app.prep_proj_axes, ...
                'XData', [1 app.prep_proj_axes.Position(3)], ...
                'YData', [1 app.prep_proj_axes.Position(4)]);
            % Set limits of axes
            app.prep_proj_axes.XLim = [0 I.XData(2)];
            app.prep_proj_axes.YLim = [0 I.YData(2)];
           set(app.prep_proj_axes,'Xtick',[],'Xticklabel',[],'Ytick',[],'Yticklabel',[])

           daspect(app.prep_proj_axes,[1 1 1])
           colormap(app.prep_proj_axes,'hot')   
        end

        function disp_run_projection(app,S)
            
            % Display image and stretch to fill axes
            I = imagesc(app.projections_cell_array{S}, 'Parent', app.run_proj_axes, ...
                'XData', [1 app.run_proj_axes.Position(3)], ...
                'YData', [1 app.run_proj_axes.Position(4)]);
            % Set limits of axes
            app.run_proj_axes.XLim = [0 I.XData(2)];
            app.run_proj_axes.YLim = [0 I.YData(2)];
           set(app.run_proj_axes,'Xtick',[],'Xticklabel',[],'Ytick',[],'Yticklabel',[])

           daspect(app.run_proj_axes,[1 1 1])
           colormap(app.run_proj_axes,'hot')   
        end
        
        function projection_set = insert_mat_into_cell(app,input)
            ht_screen = app.Screenheightpixels.Value;
            wd_screen = app.Screenwidthpixels.Value;
        
            ht_image = size(input,1);
            wd_image = size(input,2);
            
            % Define the position of the projection within the full size projected
            % image
            if mod(ht_image,2) == 0
                screen_rows = ht_screen/2 - ht_image/2 : ht_screen/2 + ht_image/2-1;
            else
                screen_rows = ht_screen/2 - (round(ht_image/2)-1) : ht_screen/2 + (round(ht_image/2)-1);  %need this when width is odd number
            end
        
            if mod(wd_image,2) == 0
                screen_cols = wd_screen/2 - wd_image/2 : wd_screen/2 + wd_image/2-1;
            else
                screen_cols = wd_screen/2 - (round(wd_image/2)-1) : wd_screen/2 + (round(wd_image/2)-1);  %need this when width is odd number
            end
        
           
            projection_set = cell(1,size(input,3));
            
            for ii = 1:size(input,3)
                curr_image = zeros(ht_screen,wd_screen);
                curr_image(screen_rows,screen_cols) = squeeze(input(:,:,ii));
                projection_set{ii} = uint8(curr_image);
            end
            
        end


        function size_scale(app,S)
              
              ySize = size(app.projections_cell_array{1}, 1);
              xSize = size(app.projections_cell_array{1}, 2);

              yCrop = floor(ySize / 2 * abs(app.Sizescalefactor.Value - 1));
              xCrop = floor(xSize / 2 * abs(app.Sizescalefactor.Value - 1));

              scaled_image = imresize(app.projections_cell_array{S}, app.Sizescalefactor.Value);
              ySizeZ = size(scaled_image, 1);
              xSizeZ = size(scaled_image, 2);
              
              if app.Sizescalefactor.Value > 1
                  app.projections_cell_array{S} = scaled_image(1+yCrop:yCrop+ySize, 1+xCrop:xCrop+xSize);
              else
                  app.projections_cell_array{S} = zeros(ySize, xSize);
                  app.projections_cell_array{S}( 1+yCrop:yCrop+ySizeZ, 1+xCrop:xCrop+xSizeZ) = scaled_image;
              end
        end
        
        function intensity_scale(app,S)
            app.projections_cell_array{S} = app.projections_cell_array{S}.*app.Intensityscalefactor.Value;
        end
        
        function reset_parameters(app)
            app.Screenwidthpixels.Value = 2716;
            app.Screenheightpixels.Value = 1528;
            app.Sizescalefactor.Value = 1;
            app.Intensityscalefactor.Value = 1;
            app.Vertical_shift.Value = 0;
            app.Horizontal_shift.Value = 0;
            app.FlipverticalCheckBox.Value = 0;
            app.FliphorizontalCheckBox.Value = 0;
            app.ArrayverticalcopiesSpinner.Value = 1;
            app.Array_shift.Value = 0;
            app.Rotateproj.Value = 0;
        end
        
        function vertical_shift(app,S)
            app.projections_cell_array{S} = circshift(app.projections_cell_array{S},-app.Vertical_shift.Value,1);
        end
        
        function horizontal_shift(app,S)
            app.projections_cell_array{S} = circshift(app.projections_cell_array{S},-app.Horizontal_shift.Value,2);
        end
        
        function array_copy(app,S)
            temp_image = app.projections_cell_array{1};
            j = 0;
            for h = 2:app.ArrayverticalcopiesSpinner.Value
                if ~mod(h,2)
                    dir = 1;
                    j = j + 1;
                else
                    dir = -1;

                end
                temp = circshift(app.projections_cell_array{S},app.Array_shift.Value*j*dir,1);
                temp_image = temp + temp_image;
            end
            
            app.projections_cell_array{S} = temp_image;
            
        end
        
        function rotate_proj(app,S)
            app.projections_cell_array{S} = imrotate(app.projections_cell_array{S},app.Rotateproj.Value,'bicubic','crop');
        end
        
        function flip_vert_proj(app,S)
            app.projections_cell_array{S} = flip(app.projections_cell_array{S},1);
        end
        
        function flip_horz_proj(app,S)
            app.projections_cell_array{S} = flip(app.projections_cell_array{S},2);
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Open_proj_file_button
        function Open_proj_file_buttonButtonPushed(app, event)
            reset_parameters(app)
            [proj_file,proj_file_path] = uigetfile('*.mat');
            if isequal(proj_file,0)
               app.curr_proj_file_label.Text = 'No file selected';
            else
               app.curr_proj_file_label.Text = fullfile(proj_file_path,proj_file);
               addpath(proj_file_path);
               import_struct = load(proj_file);
               field_names = fieldnames(import_struct);
               loaded_proj = import_struct.(field_names{1});
               
               if isa(loaded_proj,'single') || isa(loaded_proj,'double') || isa(loaded_proj,'uint8')
                    app.projections_opt_mat_original = loaded_proj;
                    
                    
               elseif isa(loaded_proj,'cell')
%                    app.projections_cell_array = loaded_proj;
                    file_error_fig = uifigure;
                    uialert(file_error_fig,'Please select a matrix file not cell array','Invalid File')
                    
               else 
                    file_error_fig = uifigure;
                    uialert(file_error_fig,'Please select a single, double, or uint8 matrix','Invalid File')                    
               end


               app.projections_opt_mat_original = flip(permute(app.projections_opt_mat_original,[3 1 2]),1);

               app.projections_mat = app.projections_opt_mat_original;


               app.prep_proj_slider.Enable = 'On';
               app.prep_proj_slice_label.Enable = 'On';
               
               app.projections_cell_array_original = insert_mat_into_cell(app,app.projections_mat);
               app.projections_cell_array = app.projections_cell_array_original;
               
               app.prep_proj_slider.Limits = [1,size(app.projections_cell_array_original,2)];
               app.run_proj_slider.Limits = [1,size(app.projections_cell_array_original,2)];

               
               disp_prep_projection(app,1)


            end
           
        end

        % Value changed function: run_proj_slider
        function run_proj_sliderValueChanged(app, event)
            S = round(app.run_proj_slider.Value);
   
            disp_run_projection(app,S)
            
            if size(app.projections_cell_array,2) > 1
                app.run_proj_slice_label.Text = sprintf('Slice# %d / %d',S, size(app.projections_cell_array,2));
            else
                app.run_proj_slice_label.Text = sprintf('2D image');
            end
        end

        % Button pushed function: Start_proj_button
        function Start_proj_buttonButtonPushed(app, event)
            app.run_proj_slider.Enable = 'Off';
        end

        % Button pushed function: Stop_proj_button
        function Stop_proj_buttonButtonPushed(app, event)
            app.run_proj_slider.Enable = 'On';
            
        end

        % Value changed function: prep_proj_slider
        function prep_proj_sliderValueChanged(app, event)
            S = round(app.prep_proj_slider.Value);
   
            disp_prep_projection(app,S)
            
            if size(app.projections_cell_array,2) > 1
                app.prep_proj_slice_label.Text = sprintf('Slice# %d / %d',S, size(app.projections_cell_array,2));
            else
                app.prep_proj_slice_label.Text = sprintf('2D image');
            end
   
        end

        % Button pushed function: Resetparameters
        function ResetparametersButtonPushed(app, event)
            reset_parameters(app)
        end

        % Button pushed function: prep_proj_button
        function prep_proj_ButtonPushed(app, event)
            app.projections_cell_array = app.projections_cell_array_original;    
        
%             if app.Screenwidthpixels.Value ~= 2716 || app.Screenheightpixels.Value ~= 1528
%                 app.projections_cell_array = insert_mat_into_cell(app,app.projections_mat);
%                 app.prep_proj_slider.Limits = [1,size(app.projections_cell_array_original,2)];
%                 
%             end
            
            if app.Sizescalefactor.Value ~= 1
                size_scale(app,1)
            end
            
            if app.Intensityscalefactor.Value ~= 1
                intensity_scale(app,1)
            end
            
            if app.FlipverticalCheckBox.Value ~= 0
                flip_vert_proj(app,1)
            end
            
            if app.FliphorizontalCheckBox.Value ~= 0
                flip_horz_proj(app,1)
            end
            
            if app.Vertical_shift.Value ~= 0 
                vertical_shift(app,1)
            end
            
            if app.Horizontal_shift.Value ~= 0
                horizontal_shift(app,1)
            end
            
            if app.ArrayverticalcopiesSpinner.Value ~= 1
                array_copy(app,1)
            end
            
            if app.Rotateproj.Value ~= 0 
                rotate_proj(app,1)
            end
            
           disp_prep_projection(app,1)
            
        end

        % Button pushed function: Createprojectionset
        function CreateprojectionsetButtonPushed(app, event)
            app.projections_cell_array = app.projections_cell_array_original;
            
            for ii = 1:size(app.projections_cell_array,2)
                if app.Sizescalefactor.Value ~= 1
                    size_scale(app,ii)
                end
                
                if app.Intensityscalefactor.Value ~= 1
                    intensity_scale(app,ii)
                end
                
                if app.FlipverticalCheckBox.Value ~= 0
                    flip_vert_proj(app,ii)
                end
                
                if app.FliphorizontalCheckBox.Value ~= 0
                    flip_horz_proj(app,ii)
                end
                
                if app.Vertical_shift.Value ~= 0 
                    vertical_shift(app,ii)
                end
                
                if app.Horizontal_shift.Value ~= 0
                    horizontal_shift(app,ii)
                end
                
                if app.ArrayverticalcopiesSpinner.Value ~= 1
                    array_copy(app,ii)
                end
                
                if app.Rotateproj.Value ~= 0 
                    rotate_proj(app,ii)
                end
                
                if mod(ii,10) == 0
                    disp_prep_projection(app,ii)
                    pause(0.01)
                end

            end
            disp_run_projection(app,1)         
        end

        % Button pushed function: Saveprojectionset
        function SaveprojectionsetButtonPushed(app, event)
            [save_file_name,save_file_path] = uiputfile('*.mat');
            save_data = app.projections_cell_array;
            save(fullfile(save_file_path,save_file_name),'save_data');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Untitled';
            app.TabGroup = uitabgroup(app.figure1);
            app.Projection_prep_tab = uitab(app.TabGroup);
            app.Projection_prep_tab.Title = 'Prepare Projections';
            app.Parameters = uipanel(app.Projection_prep_tab);
            app.Parameters.Title = 'Parameters';
            app.FlipverticalCheckBox = uicheckbox(app.Parameters);
            app.FlipverticalCheckBox.Text = 'Flip vertical';
            app.FliphorizontalCheckBox = uicheckbox(app.Parameters);
            app.FliphorizontalCheckBox.Text = 'Flip horizontal';
            app.ArrayverticalcopiesSpinnerLabel = uilabel(app.Parameters);
            app.ArrayverticalcopiesSpinnerLabel.Text = 'Array vertical (# copies):';
            app.ArrayverticalcopiesSpinner = uispinner(app.Parameters);
            app.ArrayverticalcopiesSpinner.Limits = [1 Inf];
            app.ArrayverticalcopiesSpinner.RoundFractionalValues = 'on';
            app.ArrayverticalcopiesSpinner.Value = 1;
            app.RotateLabel = uilabel(app.Parameters);
            app.RotateLabel.Text = 'Rotate (°):';
            app.Rotateproj = uieditfield(app.Parameters, 'numeric');
            app.ScreenwidthpixelsLabel = uilabel(app.Parameters);
            app.ScreenwidthpixelsLabel.Text = 'Screen width (pixels):';
            app.Screenwidthpixels = uieditfield(app.Parameters, 'numeric');
            app.Screenwidthpixels.Limits = [0 2716];
            app.Screenwidthpixels.RoundFractionalValues = 'on';
            app.Screenwidthpixels.Value = 2716;
            app.ScreenheightpixelsLabel = uilabel(app.Parameters);
            app.ScreenheightpixelsLabel.Text = 'Screen height (pixels):';
            app.Screenheightpixels = uieditfield(app.Parameters, 'numeric');
            app.Screenheightpixels.Limits = [0 1528];
            app.Screenheightpixels.RoundFractionalValues = 'on';
            app.Screenheightpixels.Value = 1528;
            app.SizescalefactorLabel = uilabel(app.Parameters);
            app.SizescalefactorLabel.Text = 'Size scale factor:';
            app.Sizescalefactor = uieditfield(app.Parameters, 'numeric');
            app.Sizescalefactor.Limits = [0 Inf];
            app.Sizescalefactor.Value = 1;
            app.IntensityscalefactorLabel = uilabel(app.Parameters);
            app.IntensityscalefactorLabel.Text = 'Intensity scale factor:';
            app.Intensityscalefactor = uieditfield(app.Parameters, 'numeric');
            app.Intensityscalefactor.Limits = [0 Inf];
            app.Intensityscalefactor.Value = 1;
            app.Resetparameters = uibutton(app.Parameters, 'push');
            app.Resetparameters.ButtonPushedFcn = createCallbackFcn(app, @ResetparametersButtonPushed, true);
            app.Resetparameters.Text = 'Reset';
            app.prep_proj_button = uibutton(app.Parameters, 'push');
            app.prep_proj_button.ButtonPushedFcn = createCallbackFcn(app, @prep_proj_ButtonPushed, true);
            app.prep_proj_button.Text = 'Preview projection';
            app.ArrayverticalshiftpixelsLabel = uilabel(app.Parameters);
            app.ArrayverticalshiftpixelsLabel.Text = 'Array vertical shift (pixels):';
            app.Array_shift = uieditfield(app.Parameters, 'numeric');
            app.Array_shift.Limits = [0 1080];
            app.Array_shift.RoundFractionalValues = 'on';
            app.VerticalshiftpixelsLabel = uilabel(app.Parameters);
            app.VerticalshiftpixelsLabel.Text = 'Vertical shift (pixels):';
            app.Vertical_shift = uieditfield(app.Parameters, 'numeric');
            app.Vertical_shift.RoundFractionalValues = 'on';
            app.HorizontalshiftpixelsLabel = uilabel(app.Parameters);
            app.HorizontalshiftpixelsLabel.Text = 'Horizontal shift (pixels):';
            app.Horizontal_shift = uieditfield(app.Parameters, 'numeric');
            app.Horizontal_shift.RoundFractionalValues = 'on';
            app.FileselectionPanel = uipanel(app.Projection_prep_tab);
            app.FileselectionPanel.Title = 'File selection';
            app.Open_proj_file_button = uibutton(app.FileselectionPanel, 'push');
            app.Open_proj_file_button.ButtonPushedFcn = createCallbackFcn(app, @Open_proj_file_buttonButtonPushed, true);
            app.Open_proj_file_button.Text = 'Open optimized projection matrix (.mat) file';
            app.curr_proj_file_label = uilabel(app.FileselectionPanel);
            app.curr_proj_file_label.Text = 'No file selected';
            app.PreviewprojectionsPanel = uipanel(app.Projection_prep_tab);
            app.PreviewprojectionsPanel.Title = 'Preview projections';
            app.prep_proj_axes = uiaxes(app.PreviewprojectionsPanel);
            app.prep_proj_axes.CLim = [0 1];
            app.prep_proj_axes.ALim = [0 1];
            app.prep_proj_axes.XAxisLocation = 'origin';
            app.prep_proj_axes.XTick = [];
            app.prep_proj_axes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.prep_proj_axes.YAxisLocation = 'origin';
            app.prep_proj_axes.YTick = [];
            app.prep_proj_axes.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.prep_proj_axes.ZTick = [0 0.5 1];
            app.prep_proj_axes.ZTickLabel = '';
            app.prep_proj_slider = uislider(app.PreviewprojectionsPanel);
            app.prep_proj_slider.MajorTicks = [];
            app.prep_proj_slider.ValueChangedFcn = createCallbackFcn(app, @prep_proj_sliderValueChanged, true);
            app.prep_proj_slider.MinorTicks = [];
            app.prep_proj_slider.Enable = 'off';
            app.prep_proj_slice_label = uilabel(app.PreviewprojectionsPanel);
            app.prep_proj_slice_label.Enable = 'off';
            app.prep_proj_slice_label.Text = '';
            app.Createprojectionset = uibutton(app.PreviewprojectionsPanel, 'push');
            app.Createprojectionset.ButtonPushedFcn = createCallbackFcn(app, @CreateprojectionsetButtonPushed, true);
            app.Createprojectionset.Text = 'Create projection set';
            app.Saveprojectionset = uibutton(app.PreviewprojectionsPanel, 'push');
            app.Saveprojectionset.ButtonPushedFcn = createCallbackFcn(app, @SaveprojectionsetButtonPushed, true);
            app.Saveprojectionset.Text = 'Save projection set';
            app.Projection_tab = uitab(app.TabGroup);
            app.Projection_tab.Title = 'Project';
            app.run_proj_axes = uiaxes(app.Projection_tab);
            app.run_proj_axes.XLim = [0 1];
            app.run_proj_axes.YLim = [0 1];
            app.run_proj_axes.ZLim = [0 1];
            app.run_proj_axes.CLim = [0 1];
            app.run_proj_axes.ALim = [0 1];
            app.run_proj_axes.XTick = [];
            app.run_proj_axes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.run_proj_axes.YTick = [];
            app.run_proj_axes.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.run_proj_axes.ZTick = [0 0.5 1];
            app.run_proj_axes.ZTickLabel = '';
            app.Start_proj_button = uibutton(app.Projection_tab, 'push');
            app.Start_proj_button.ButtonPushedFcn = createCallbackFcn(app, @Start_proj_buttonButtonPushed, true);
            app.Start_proj_button.Enable = 'off';
            app.Start_proj_button.Text = 'Start';
            app.Stop_proj_button = uibutton(app.Projection_tab, 'push');
            app.Stop_proj_button.ButtonPushedFcn = createCallbackFcn(app, @Stop_proj_buttonButtonPushed, true);
            app.Stop_proj_button.Enable = 'off';
            app.Stop_proj_button.Text = 'Stop';
            app.run_proj_slider = uislider(app.Projection_tab);
            app.run_proj_slider.MajorTicks = [];
            app.run_proj_slider.ValueChangedFcn = createCallbackFcn(app, @run_proj_sliderValueChanged, true);
            app.run_proj_slider.MinorTicks = [];
            app.run_proj_slice_label = uilabel(app.Projection_tab);
            app.run_proj_slice_label.Text = '';
            app.Motorcontrolpanel = uipanel(app.Projection_tab);
            app.Motorcontrolpanel.Title = 'Motor control';
            app.rot_vel_label = uilabel(app.Motorcontrolpanel);
            app.rot_vel_label.Text = 'Rot. velocity (°/s)';
            app.curr_rot_vel = uieditfield(app.Motorcontrolpanel, 'text');
            app.curr_rot_vel.Enable = 'off';
            app.curr_rot_vel.Value = '0';
            app.rot_acc_label = uilabel(app.Motorcontrolpanel);
            app.rot_acc_label.Text = 'Rot. acceleration (°/s/s)';
            app.curr_rot_acc = uieditfield(app.Motorcontrolpanel, 'text');
            app.curr_rot_acc.Enable = 'off';
            app.curr_rot_acc.Value = '0';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proj_control_GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end